classdef CO2_SerialPort < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Label_Tip              matlab.ui.control.Label
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        UIAxes                 matlab.ui.control.UIAxes
        StartButton            matlab.ui.control.StateButton
        Lamp                   matlab.ui.control.Lamp
        Panel                  matlab.ui.container.Panel
        CheckBox_Ref           matlab.ui.control.CheckBox
        CheckBox_Act           matlab.ui.control.CheckBox
        CheckBox_Temperature   matlab.ui.control.CheckBox
        CheckBox_Common        matlab.ui.control.CheckBox
        CheckBox_Sub           matlab.ui.control.CheckBox
        CheckBox_Div           matlab.ui.control.CheckBox
        CheckBox_T             matlab.ui.control.CheckBox
        Label                  matlab.ui.control.Label
        DropDown_SerialPort    matlab.ui.control.DropDown
        DropDown_2Label        matlab.ui.control.Label
        DropDown_Baudrate      matlab.ui.control.DropDown
        Tab2                   matlab.ui.container.Tab
        StartButton_2          matlab.ui.control.StateButton
        Lamp_2                 matlab.ui.control.Lamp
        Label_2                matlab.ui.control.Label
        DropDown_SerialPort_2  matlab.ui.control.DropDown
        DropDown_2Label_2      matlab.ui.control.Label
        DropDown_Baudrate_2    matlab.ui.control.DropDown
        TemperatureLabel       matlab.ui.control.Label
        HumidityLabel          matlab.ui.control.Label
        Gauge_T                matlab.ui.control.LinearGauge
        Gauge_H                matlab.ui.control.LinearGauge
        Label_T                matlab.ui.control.Label
        Label_H                matlab.ui.control.Label
        UIAxes_T               matlab.ui.control.UIAxes
        UIAxes_H               matlab.ui.control.UIAxes
        SaveParamsButton       matlab.ui.control.Button
        Tab3                   matlab.ui.container.Tab
        SliderLabel            matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
        Slider2Label           matlab.ui.control.Label
        Slider2                matlab.ui.control.Slider
    end


    properties (Access = private)
        SerialHandle
        RefData % Description
        ActData % Description
        TempData % Description
        ComData % Description
        T % Description
        SubData % Description
        DivData % Description
        x % Description
        SerialHandle_2 % Description
        SHT_T % Description
        SHT_H % Description
        SHT_x % Description
        floder_name % Description
    end

    properties (Access = public)
    end

    methods (Access = private)
    
        function SerialCallback(app, ~)
            out = fscanf(app.SerialHandle);
            app.Label_Tip.Text = out;
            
            if strncmp(out, '#', 1)
                app.Label_Tip.Value = out;
            else
                SerialDataProcess(app, out)
            end
        end
        
        function results = ScanSysSerialPort(~)
            SYS_Ports = instrhwinfo('Serial');
            SYS_AvailablePorts = SYS_Ports.AvailableSerialPorts;
            results = SYS_AvailablePorts';
        end
        
        function SerialDataProcess(app, data)
            try
                sensor_value = sscanf(data, '%f');
                
                if isempty(app.x)
                    app.x = 0;
                else
                    app.x = [app.x length(app.x)];
                end
                
                if isempty(app.ActData)
                    app.ActData = sensor_value(1);
                else
                    app.ActData = [app.ActData sensor_value(1)];
                end
                
                if isempty(app.RefData)
                    app.RefData = sensor_value(2);
                else
                    app.RefData = [app.RefData sensor_value(2)];
                end
                
                if isempty(app.ComData)
                    app.ComData = sensor_value(3);
                else
                    app.ComData = [app.ComData sensor_value(3)];
                end
                
                if isempty(app.TempData)
                    app.TempData = sensor_value(4);
                else
                    app.TempData = [app.TempData sensor_value(4)];
                end
                
                if isempty(app.T)
                    app.T = sensor_value(5);
                else
                    app.T = [app.T sensor_value(5)];
                end
                
                if isempty(app.SubData)
                    app.SubData = sensor_value(6);
                else
                    app.SubData = [app.SubData sensor_value(6)];
                end
                
                if isempty(app.DivData)
                    app.DivData = sensor_value(7);
                else
                    app.DivData = [app.DivData sensor_value(7)]; 
                end
                
%                 table_data = [app.ActData' app.RefData' app.ComData' app.TempData' app.T' app.SubData' app.DivData'];
%                 app.UITable.Data = table_data;
                
                updateAxes(app);
                
            catch ex
                
                msgbox(ex.message);
            end
        end
        
        function updateAxes(app)
            try
                cla(app.UIAxes)
                hold(app.UIAxes,'on')
                Item_legend = [];
                
                if app.CheckBox_Act.Value == 1
                    plot(app.UIAxes, app.x, app.ActData);
                    Item_legend = [Item_legend; 'Act.'];
                end
                
                if app.CheckBox_Ref.Value == 1
                    plot(app.UIAxes, app.x, app.RefData);
                    Item_legend = [Item_legend; 'Ref.'];
                end
                
                if app.CheckBox_Common.Value == 1
                    plot(app.UIAxes, app.x, app.ComData);
                    Item_legend = [Item_legend; 'Com.'];
                end
                
                if app.CheckBox_Temperature.Value == 1
                    plot(app.UIAxes, app.x, app.TempData);
                    Item_legend = [Item_legend; 'Tem.'];
                end
                
                if app.CheckBox_T.Value == 1
                    plot(app.UIAxes, app.x, app.T);
                    Item_legend = [Item_legend; 'T...'];
                end
                
                if app.CheckBox_Sub.Value == 1
                    plot(app.UIAxes, app.x, app.SubData);
                    Item_legend = [Item_legend; 'Sub.'];
                end
                
                if app.CheckBox_Div.Value == 1
                    plot(app.UIAxes, app.x, app.DivData);
                    Item_legend = [Item_legend; 'Div.'];
                end
                
                legend(app.UIAxes, Item_legend);
            catch ex
                msgbox(ex.message);
            end
        end
        
        function SerialCallback_2(app, event)
            try
                %out = fread(app.SerialHandle_2, app.SerialHandle_2.BytesAvailable, 'uint8');
                out = fscanf(app.SerialHandle_2);
                RxData = sscanf(out, '%f');
                v_T = RxData(2);
                v_H = RxData(1);
                app.Gauge_T.Value = v_T;
                app.Gauge_H.Value = v_H;
                app.Label_H.Text = num2str(v_H);
                app.Label_T.Text = num2str(v_T);
                
                if isempty(app.SHT_x)
                    app.SHT_x = 0;
                else
                    app.SHT_x = [app.SHT_x length(app.SHT_x)];
                end
                
                app.SHT_T = [app.SHT_T v_T];
                app.SHT_H = [app.SHT_H v_H];
                
                plot(app.UIAxes_T, app.SHT_x, app.SHT_T);
                plot(app.UIAxes_H, app.SHT_x, app.SHT_H);
                
                time = datestr(now, 'yyyy-mm-dd HH:MM:SS >> ');
                fid = fopen([app.floder_name 'SHT_Log.txt'], 'a+');
                fprintf(fid, [time num2str(v_T) ' ' num2str(v_H) '\r\n']);
                fclose(fid);
            catch ex
                msgbox(ex.message);
                %app.StartButton.Value = 0;
                %StartButtonValueChanged(app, event);
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            item = [app.ScanSysSerialPort 'Refresh'];
            app.DropDown_SerialPort.Items = item;
            app.DropDown_SerialPort_2.Items = item;
            
            app.floder_name = [pwd '\obj\'];
            [status, msg, msgID] = mkdir(app.floder_name);
            
            fid = fopen([app.floder_name 'SHT_Log.txt'], 'w+');
            fclose(fid);
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            try
                switch(value)
                    case 0   %弹起
                        fclose(app.SerialHandle);
                        app.Lamp.Color = [1 0 0];
                    case 1   %按下
                        app.SerialHandle = serial(app.DropDown_SerialPort.Value);
                        app.SerialHandle.BaudRate = str2double(app.DropDown_Baudrate.Value);
                        app.SerialHandle.Terminator = 'LF';
                        app.SerialHandle.BytesAvailableFcnMode = 'Terminator';
                        app.SerialHandle.BytesAvailableFcn = createCallbackFcn(app, @SerialCallback, true);
                        fopen(app.SerialHandle);
                        
                        app.Lamp.Color = [0 1 0];
                end
            catch ex
                if ~isempty(app.SerialHandle)
                    switch(app.SerialHandle.status)
                        case 'open'
                            app.StartButton.Value = 1;
                            app.Lamp.Color = [0 1 0];
                        case 'closed'
                            app.StartButton.Value = 0;
                            app.Lamp.Color = [1 0 0];
                        otherwise
                    end
                end
                msgbox(ex.message)
            end
        end

        % Value changed function: DropDown_SerialPort, 
        % DropDown_SerialPort_2
        function DropDown_SerialPortValueChanged(app, event)
            value = app.DropDown_SerialPort.Value;
            if strcmp(value, 'Refresh')
                app.DropDown_SerialPort.Items = [app.ScanSysSerialPort 'Refresh'];
                app.DropDown_SerialPort.Value = app.DropDown_SerialPort.Items(1);
            end
            
            value = app.DropDown_SerialPort_2.Value;
            if strcmp(value, 'Refresh')
                app.DropDown_SerialPort_2.Items = [app.ScanSysSerialPort 'Refresh'];
                app.DropDown_SerialPort_2.Value = app.DropDown_SerialPort.Items(1);
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            try
                if ~isempty(app.SerialHandle)
                    if strcmp('open', app.SerialHandle.status)
                        fclose(app.SerialHandle);
                    end
                end
                if ~isempty(app.SerialHandle_2)
                    if strcmp('open', app.SerialHandle_2.status)
                        fclose(app.SerialHandle_2);
                    end
                end
            catch ex
                msgbox(ex.message);
            end
            delete(app);
        end

        % Value changed function: CheckBox_Act, CheckBox_Common, 
        % CheckBox_Div, CheckBox_Ref, CheckBox_Sub, CheckBox_T, 
        % CheckBox_Temperature
        function CheckBox_ValueChanged(app, event)
            value = app.CheckBox_Ref.Value;
            updateAxes(app);
        end

        % Value changed function: StartButton_2
        function StartButton_2ValueChanged(app, event)
            value = app.StartButton_2.Value;
            try
                switch(value)
                    case 0   %弹起
                        fclose(app.SerialHandle_2);
                        app.Lamp_2.Color = [1 0 0];
                    case 1   %按下
                        app.SerialHandle_2 = serial(app.DropDown_SerialPort_2.Value);
                        app.SerialHandle_2.BaudRate = str2double(app.DropDown_Baudrate_2.Value);
                        app.SerialHandle_2.Terminator = 'LF';
                        app.SerialHandle_2.BytesAvailableFcnMode = 'Terminator';
                        app.SerialHandle_2.BytesAvailableFcn = createCallbackFcn(app, @SerialCallback_2, true);
                        fopen(app.SerialHandle_2);
                        
                        app.Lamp_2.Color = [0 1 0];
                end
            catch ex
                switch(app.SerialHandle_2.status)
                    case 'open'
                        app.StartButton_2.Value = 1;
                        app.Lamp_2.Color = [0 1 0];
                    case 'closed'
                        app.StartButton_2.Value = 0;
                        app.Lamp_2.Color = [1 0 0];
                    otherwise
                end
                msgbox(ex.message)
            end
        end

        % Button pushed function: SaveParamsButton
        function SaveParamsButtonPushed(app, event)
            try
                [fname, fpath] = uiputfile('SHT_Param.mat', 'Save SHT_Param');
                
                if fname~=0
                    var_SHT_T = app.SHT_T;
                    var_SHT_H = app.SHT_H;
                    if fpath~=0
                        save([fpath fname], 'var_SHT_T', 'var_SHT_H'); 
                    else
                        save(fname, 'var_SHT_T', 'var_SHT_H'); 
                    end
                    clear var_SHT_H;
                    clear var_SHT_T;
                    if exist([app.floder_name 'SHT_Log.txt'],'file')==2
                        sn = [fp 'SHT_Log.txt'];
                        sn_i = 1;
                        while exist(sn,'file')==2
                            sn = [fp 'SHT_Log_' num2str(sn_i) '.txt'];
                        end
                        copyfile([app.floder_name 'SHT_Log.txt'], sn);
                    end
                end
            catch ex
                msgbox(ex.message);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label_Tip = uilabel(app.UIFigure);
            app.Label_Tip.Text = '';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartButton = uibutton(app.Tab, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.Lamp = uilamp(app.Tab);
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '显示通道';
            app.CheckBox_Ref = uicheckbox(app.Panel);
            app.CheckBox_Ref.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ValueChanged, true);
            app.CheckBox_Ref.Text = '参考通道';
            app.CheckBox_Ref.Value = true;
            app.CheckBox_Act = uicheckbox(app.Panel);
            app.CheckBox_Act.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ValueChanged, true);
            app.CheckBox_Act.Text = '工作通道';
            app.CheckBox_Act.Value = true;
            app.CheckBox_Temperature = uicheckbox(app.Panel);
            app.CheckBox_Temperature.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ValueChanged, true);
            app.CheckBox_Temperature.Text = '温度通道';
            app.CheckBox_Common = uicheckbox(app.Panel);
            app.CheckBox_Common.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ValueChanged, true);
            app.CheckBox_Common.Text = '公共通道';
            app.CheckBox_Sub = uicheckbox(app.Panel);
            app.CheckBox_Sub.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ValueChanged, true);
            app.CheckBox_Sub.Text = '差值通道';
            app.CheckBox_Div = uicheckbox(app.Panel);
            app.CheckBox_Div.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ValueChanged, true);
            app.CheckBox_Div.Text = '商值通道';
            app.CheckBox_T = uicheckbox(app.Panel);
            app.CheckBox_T.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ValueChanged, true);
            app.CheckBox_T.Text = '温度';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '端口号';
            app.DropDown_SerialPort = uidropdown(app.Tab);
            app.DropDown_SerialPort.Items = {};
            app.DropDown_SerialPort.ValueChangedFcn = createCallbackFcn(app, @DropDown_SerialPortValueChanged, true);
            app.DropDown_SerialPort.Value = {};
            app.DropDown_2Label = uilabel(app.Tab);
            app.DropDown_2Label.Text = '波特率';
            app.DropDown_Baudrate = uidropdown(app.Tab);
            app.DropDown_Baudrate.Items = {'9600', '19200', '115200'};
            app.DropDown_Baudrate.Value = '115200';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.StartButton_2 = uibutton(app.Tab2, 'state');
            app.StartButton_2.ValueChangedFcn = createCallbackFcn(app, @StartButton_2ValueChanged, true);
            app.StartButton_2.Text = 'Start';
            app.Lamp_2 = uilamp(app.Tab2);
            app.Label_2 = uilabel(app.Tab2);
            app.Label_2.Text = '端口号';
            app.DropDown_SerialPort_2 = uidropdown(app.Tab2);
            app.DropDown_SerialPort_2.Items = {};
            app.DropDown_SerialPort_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_SerialPortValueChanged, true);
            app.DropDown_SerialPort_2.Value = {};
            app.DropDown_2Label_2 = uilabel(app.Tab2);
            app.DropDown_2Label_2.Text = '波特率';
            app.DropDown_Baudrate_2 = uidropdown(app.Tab2);
            app.DropDown_Baudrate_2.Items = {'9600', '19200', '115200'};
            app.DropDown_Baudrate_2.Value = '115200';
            app.TemperatureLabel = uilabel(app.Tab2);
            app.TemperatureLabel.Text = 'Temperature:';
            app.HumidityLabel = uilabel(app.Tab2);
            app.HumidityLabel.Text = 'Humidity:';
            app.Gauge_T = uigauge(app.Tab2, 'linear');
            app.Gauge_H = uigauge(app.Tab2, 'linear');
            app.Label_T = uilabel(app.Tab2);
            app.Label_T.Text = '0';
            app.Label_H = uilabel(app.Tab2);
            app.Label_H.Text = '0';
            app.UIAxes_T = uiaxes(app.Tab2);
            title(app.UIAxes_T, '温度')
            xlabel(app.UIAxes_T, 'X')
            ylabel(app.UIAxes_T, 'Y')
            app.UIAxes_H = uiaxes(app.Tab2);
            title(app.UIAxes_H, '湿度')
            xlabel(app.UIAxes_H, 'X')
            ylabel(app.UIAxes_H, 'Y')
            app.SaveParamsButton = uibutton(app.Tab2, 'push');
            app.SaveParamsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParamsButtonPushed, true);
            app.SaveParamsButton.Text = 'Save Params';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.SliderLabel = uilabel(app.Tab3);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.Tab3);
            app.Slider2Label = uilabel(app.Tab3);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.Tab3);
        end
    end

    methods (Access = public)

        % Construct app
        function app = CO2_SerialPort

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end